classdef DEM_demo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel39        matlab.ui.container.Panel
        pushbutton309    matlab.ui.control.Button
        uipanel38        matlab.ui.container.Panel
        pushbutton298    matlab.ui.control.Button
        pushbutton296    matlab.ui.control.Button
        pushbutton295    matlab.ui.control.Button
        pushbutton294    matlab.ui.control.Button
        pushbutton293    matlab.ui.control.Button
        pushbutton292    matlab.ui.control.Button
        pushbutton291    matlab.ui.control.Button
        pushbutton290    matlab.ui.control.Button
        pushbutton289    matlab.ui.control.Button
        pushbutton288    matlab.ui.control.Button
        pushbutton287    matlab.ui.control.Button
        pushbutton286    matlab.ui.control.Button
        uipanel37        matlab.ui.container.Panel
        pushbutton284    matlab.ui.control.Button
        pushbutton283    matlab.ui.control.Button
        pushbutton282    matlab.ui.control.Button
        pushbutton266    matlab.ui.control.Button
        uipanel28        matlab.ui.container.Panel
        pushbutton300    matlab.ui.control.Button
        pushbutton251    matlab.ui.control.Button
        pushbutton249    matlab.ui.control.Button
        pushbutton248    matlab.ui.control.Button
        pushbutton250    matlab.ui.control.Button
        pushbutton261    matlab.ui.control.Button
        pushbutton252    matlab.ui.control.Button
        text2            matlab.ui.control.Label
        uipanel34        matlab.ui.container.Panel
        pushbutton285    matlab.ui.control.Button
        pushbutton269    matlab.ui.control.Button
        pushbutton268    matlab.ui.control.Button
        pushbutton267    matlab.ui.control.Button
        pushbutton259    matlab.ui.control.Button
        pushbutton258    matlab.ui.control.Button
        uipanel32        matlab.ui.container.Panel
        pushbutton243    matlab.ui.control.Button
        pushbutton242    matlab.ui.control.Button
        pushbutton241    matlab.ui.control.Button
        pushbutton226    matlab.ui.control.Button
        pushbutton240    matlab.ui.control.Button
        pushbutton239    matlab.ui.control.Button
        uipanel31        matlab.ui.container.Panel
        pushbutton230    matlab.ui.control.Button
        pushbutton227    matlab.ui.control.Button
        pushbutton224    matlab.ui.control.Button
        pushbutton210    matlab.ui.control.Button
        pushbutton202    matlab.ui.control.Button
        pushbutton201    matlab.ui.control.Button
        pushbutton200    matlab.ui.control.Button
        pushbutton199    matlab.ui.control.Button
        pushbutton198    matlab.ui.control.Button
        pushbutton197    matlab.ui.control.Button
        uipanel27        matlab.ui.container.Panel
        pushbutton301    matlab.ui.control.Button
        pushbutton299    matlab.ui.control.Button
        pushbutton262    matlab.ui.control.Button
        pushbutton228    matlab.ui.control.Button
        pushbutton154    matlab.ui.control.Button
        pushbutton151    matlab.ui.control.Button
        uipanel26        matlab.ui.container.Panel
        pushbutton307    matlab.ui.control.Button
        pushbutton302    matlab.ui.control.Button
        pushbutton265    matlab.ui.control.Button
        pushbutton263    matlab.ui.control.Button
        pushbutton244    matlab.ui.control.Button
        pushbutton234    matlab.ui.control.Button
        pushbutton225    matlab.ui.control.Button
        pushbutton214    matlab.ui.control.Button
        pushbutton212    matlab.ui.control.Button
        pushbutton211    matlab.ui.control.Button
        pushbutton170    matlab.ui.control.Button
        pushbutton169    matlab.ui.control.Button
        pushbutton168    matlab.ui.control.Button
        pushbutton163    matlab.ui.control.Button
        pushbutton161    matlab.ui.control.Button
        pushbutton152    matlab.ui.control.Button
        pushbutton142    matlab.ui.control.Button
        pushbutton133    matlab.ui.control.Button
        pushbutton130    matlab.ui.control.Button
        uipanel7         matlab.ui.container.Panel
        pushbutton42     matlab.ui.control.Button
        pushbutton41     matlab.ui.control.Button
        pushbutton40     matlab.ui.control.Button
        pushbutton38     matlab.ui.control.Button
        pushbutton37     matlab.ui.control.Button
        pushbutton15     matlab.ui.control.Button
        uipanel3         matlab.ui.container.Panel
        pushbutton119    matlab.ui.control.Button
        pushbutton297    matlab.ui.control.Button
        pushbutton149    matlab.ui.control.Button
        pushbutton148    matlab.ui.control.Button
        pushbutton147    matlab.ui.control.Button
        pushbutton120    matlab.ui.control.Button
        pushbutton12     matlab.ui.control.Button
        pushbutton10     matlab.ui.control.Button
        pushbutton9      matlab.ui.control.Button
        pushbutton8      matlab.ui.control.Button
        pushbutton7      matlab.ui.control.Button
        pushbutton6      matlab.ui.control.Button
        pushbutton5      matlab.ui.control.Button
        uipanel24        matlab.ui.container.Panel
        pushbutton153    matlab.ui.control.Button
        pushbutton145    matlab.ui.control.Button
        pushbutton144    matlab.ui.control.Button
        pushbutton143    matlab.ui.control.Button
        pushbutton134    matlab.ui.control.Button
        pushbutton97     matlab.ui.control.Button
        uipanel23        matlab.ui.container.Panel
        pushbutton129    matlab.ui.control.Button
        pushbutton127    matlab.ui.control.Button
        pushbutton125    matlab.ui.control.Button
        pushbutton124    matlab.ui.control.Button
        pushbutton122    matlab.ui.control.Button
        pushbutton118    matlab.ui.control.Button
        pushbutton105    matlab.ui.control.Button
        pushbutton104    matlab.ui.control.Button
        pushbutton96     matlab.ui.control.Button
        pushbutton91     matlab.ui.control.Button
        uipanel19        matlab.ui.container.Panel
        pushbutton146    matlab.ui.control.Button
        pushbutton126    matlab.ui.control.Button
        pushbutton94     matlab.ui.control.Button
        pushbutton84     matlab.ui.control.Button
        pushbutton83     matlab.ui.control.Button
        uipanel17        matlab.ui.container.Panel
        pushbutton159    matlab.ui.control.Button
        pushbutton245    matlab.ui.control.Button
        pushbutton229    matlab.ui.control.Button
        pushbutton11     matlab.ui.control.Button
        uipanel16        matlab.ui.container.Panel
        pushbutton219_2  matlab.ui.control.Button
        pushbutton308    matlab.ui.control.Button
        pushbutton306    matlab.ui.control.Button
        pushbutton264    matlab.ui.control.Button
        pushbutton260    matlab.ui.control.Button
        pushbutton247    matlab.ui.control.Button
        pushbutton232    matlab.ui.control.Button
        pushbutton231    matlab.ui.control.Button
        pushbutton223    matlab.ui.control.Button
        pushbutton222    matlab.ui.control.Button
        pushbutton221    matlab.ui.control.Button
        pushbutton219    matlab.ui.control.Button
        pushbutton218    matlab.ui.control.Button
        pushbutton217    matlab.ui.control.Button
        pushbutton216    matlab.ui.control.Button
        pushbutton215    matlab.ui.control.Button
        pushbutton103    matlab.ui.control.Button
        pushbutton132    matlab.ui.control.Button
        pushbutton123    matlab.ui.control.Button
        pushbutton81     matlab.ui.control.Button
        pushbutton49     matlab.ui.control.Button
        uipanel15        matlab.ui.container.Panel
        pushbutton155    matlab.ui.control.Button
        pushbutton121    matlab.ui.control.Button
        pushbutton117    matlab.ui.control.Button
        pushbutton92     matlab.ui.control.Button
        pushbutton50     matlab.ui.control.Button
        pushbutton80     matlab.ui.control.Button
        uipanel9         matlab.ui.container.Panel
        pushbutton102    matlab.ui.control.Button
        pushbutton47     matlab.ui.control.Button
        pushbutton48     matlab.ui.control.Button
        pushbutton46     matlab.ui.control.Button
        pushbutton45     matlab.ui.control.Button
        pushbutton44     matlab.ui.control.Button
        uipanel5         matlab.ui.container.Panel
        pushbutton131    matlab.ui.control.Button
        pushbutton93     matlab.ui.control.Button
        text3            matlab.ui.control.Label
        pushbutton51     matlab.ui.control.Button
        help             matlab.ui.control.Label
        uipanel4         matlab.ui.container.Panel
        pushbutton3      matlab.ui.control.Button
        pushbutton14     matlab.ui.control.Button
        uipanel1         matlab.ui.container.Panel
        pushbutton158    matlab.ui.control.Button
        pushbutton157    matlab.ui.control.Button
        pushbutton156    matlab.ui.control.Button
        pushbutton150    matlab.ui.control.Button
        pushbutton4      matlab.ui.control.Button
        pushbutton2      matlab.ui.control.Button
        pushbutton1      matlab.ui.control.Button
        uipanel2         matlab.ui.container.Panel
    end

    
    methods (Access = private)
        function pushbutton100_Callback(app, hObject, eventdata, handles)
            handles.web = 'Dynamic modeling of neuronal responses in fMRI using cubature Kalman filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_doublewell_LAP')
        end
        
        function pushbutton101_Callback(app, hObject, eventdata, handles)
            handles.web = 'Dynamic modeling of neuronal responses in fMRI using cubature Kalman filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_hdm_SCK')
        end
        
        function pushbutton203_Callback(app, hObject, eventdata, handles)
            handles.web = 'Bayesian model selection for group studies';
            run_demo_Callback(app, hObject, handles, 'DEMO_BMR_PEB')
        end
        
        function pushbutton233_Callback(app, hObject, eventdata, handles)
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'spm_voice')
        end
        
        function pushbutton98_Callback(app, hObject, eventdata, handles)
            handles.web = 'Dynamic modeling of neuronal responses in fMRI using cubature Kalman filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_convolution_LAP')
        end
        
        function pushbutton99_Callback(app, hObject, eventdata, handles)
            handles.web = 'Dynamic modeling of neuronal responses in fMRI using cubature Kalman filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_lorenz_LAP')
        end
        
        function run_demo_Callback(app, hObject, handles, file)
            if isdeployed
                h = sprintf('%s: MATLAB code and help are not available in SPM Standalone.',file);
            else
                h  = help(file);
            end
            str{1} = [file ':'];
            str{2} = '__________________________________________________________________________ ';
            str{3} = ' ';
            str{4} = h;
            set(handles.help,'String',str);
            handles.file = file;
            set(handles.pushbutton51,'String','run demo')
            guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DEM_demo_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to DEM_demo (see VARARGIN)
            
            % Choose default command line output for DEM_demo
            handles.output = hObject;
            
            % Default paper
            handles.web    = 'The free-energy principle A unified brain theory';
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton102
        function pushbutton102_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Action understanding and active inference';
            run_demo_Callback(app, hObject, handles, 'ADEM_writing')
        end

        % Button pushed function: pushbutton103
        function pushbutton103_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Free energy and dendritic self-organization';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_dendrite')
        end

        % Button pushed function: pushbutton104
        function pushbutton104_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Free-energy and illusions the Cornsweet effect';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_Cornsweet')
        end

        % Button pushed function: pushbutton105
        function pushbutton105_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Smooth Pursuit and Visual Occlusion';
            run_demo_Callback(app, hObject, handles, 'ADEM_pursuit')
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'DEM A variational treatment of dynamic systems';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_Lorenz')
        end

        % Button pushed function: pushbutton117
        function pushbutton117_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Dopamine Affordance and Active Inference';
            run_demo_Callback(app, hObject, handles, 'ADEM_cued_response')
        end

        % Button pushed function: pushbutton118
        function pushbutton118_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Attractors in song';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MMN_deviance')
        end

        % Button pushed function: pushbutton119
        function pushbutton119_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Bayesian State Estimation Using Generalized Coordinates';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_contact_lens')
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Dynamic causal modelling';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_hdm')
        end

        % Button pushed function: pushbutton120
        function pushbutton120_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Bayesian State Estimation Using Generalized Coordinates';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_GF_and_KF')
        end

        % Button pushed function: pushbutton121
        function pushbutton121_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active inference and agency optimal control without cost functions';
            run_demo_Callback(app, hObject, handles, 'spm_MDP_mountain_car')
        end

        % Button pushed function: pushbutton122
        function pushbutton122_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Perceptions as hypotheses saccades as experiments';
            run_demo_Callback(app, hObject, handles, 'ADEM_salience')
        end

        % Button pushed function: pushbutton123
        function pushbutton123_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Perception and self-organized instability';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_SOC')
        end

        % Button pushed function: pushbutton124
        function pushbutton124_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active inference, eye movements and oculomotor delays';
            run_demo_Callback(app, hObject, handles, 'ADEM_occulomotor_delays')
        end

        % Button pushed function: pushbutton125
        function pushbutton125_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Smooth Pursuit and Visual Occlusion';
            run_demo_Callback(app, hObject, handles, 'ADEM_occlusion')
        end

        % Button pushed function: pushbutton126
        function pushbutton126_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_ALAP')
        end

        % Button pushed function: pushbutton127
        function pushbutton127_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active inference sensory attenuation and illusions';
            run_demo_Callback(app, hObject, handles, 'ALAP_demo_attenuation')
        end

        % Button pushed function: pushbutton129
        function pushbutton129_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Predictive Coding or Evidence Accumulation';
            run_demo_Callback(app, hObject, handles, 'DEM_evidence_accumulation')
        end

        % Button pushed function: pushbutton12
        function pushbutton12_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'DEM A variational treatment of dynamic systems';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_double_well')
        end

        % Button pushed function: pushbutton130
        function pushbutton130_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'The anatomy of choice active inference and agency';
            run_demo_Callback(app, hObject, handles, 'spm_MDP_offer')
        end

        % Button pushed function: pushbutton131
        function pushbutton131_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
                url = strcat('http://www.fil.ion.ucl.ac.uk/~karl/',handles.web);
                web(url,'-browser','-notoolbar');
            end
        end

        % Button pushed function: pushbutton132
        function pushbutton132_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'FEP_Manifold')
        end

        % Button pushed function: pushbutton133
        function pushbutton133_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'The anatomy of choice active inference and agency';
            run_demo_Callback(app, hObject, handles, 'spm_MDP_trust')
        end

        % Button pushed function: pushbutton134
        function pushbutton134_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'A DCM for resting state fMRI';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_induced_fMRI')
        end

        % Button pushed function: pushbutton142
        function pushbutton142_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference, Evidence Accumulation, and the Urn Task';
            run_demo_Callback(app, hObject, handles, 'spm_MDP_urn')
        end

        % Button pushed function: pushbutton143
        function pushbutton143_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Network discovery with large DCMs';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_large_fMRI')
        end

        % Button pushed function: pushbutton144
        function pushbutton144_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'On nodes and modes in resting state fMRI';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_connectivity_fMRI')
        end

        % Button pushed function: pushbutton145
        function pushbutton145_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'On nodes and modes in resting state fMRI';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_modes_fMRI')
        end

        % Button pushed function: pushbutton146
        function pushbutton146_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Dynamic modeling of neuronal responses in fMRI using cubature Kalman filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_hdm_SCK')
        end

        % Button pushed function: pushbutton147
        function pushbutton147_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_convolution_LAP')
        end

        % Button pushed function: pushbutton148
        function pushbutton148_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Dynamic modeling of neuronal responses in fMRI using cubature Kalman filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_lorenz_LAP')
        end

        % Button pushed function: pushbutton149
        function pushbutton149_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Dynamic modeling of neuronal responses in fMRI using cubature Kalman filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_doublewell_LAP')
        end

        % Button pushed function: pushbutton14
        function pushbutton14_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Variational filtering';
            run_demo_Callback(app, hObject, handles, 'DFP_demo_double_well')
        end

        % Button pushed function: pushbutton150
        function pushbutton150_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Cerebral Hierarchies, predictive processing, precision and the pulvinar';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_texture')
        end

        % Button pushed function: pushbutton151
        function pushbutton151_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'A Duet for one';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_duet')
        end

        % Button pushed function: pushbutton152
        function pushbutton152_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % --- Executes on button press in pushbutton133.
            handles.web = 'Active inference and epistemic value';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_maze')
        end

        % Button pushed function: pushbutton153
        function pushbutton153_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Post hoc Bayesian model selection';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_Bayesian_Model_Reduction')
        end

        % Button pushed function: pushbutton154
        function pushbutton154_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Knowing ones place';
            run_demo_Callback(app, hObject, handles, 'DEM_morphogenesis')
        end

        % Button pushed function: pushbutton155
        function pushbutton155_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference and Learning in the Cerebellum';
            run_demo_Callback(app, hObject, handles, 'ADEM_eyeblink')
        end

        % Button pushed function: pushbutton156
        function pushbutton156_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Post hoc Bayesian model selection';
            run_demo_Callback(app, hObject, handles, 'DEMO_SLR')
        end

        % Button pushed function: pushbutton157
        function pushbutton157_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Post hoc Bayesian model selection';
            run_demo_Callback(app, hObject, handles, 'DEMO_BMR_PEB')
        end

        % Button pushed function: pushbutton158
        function pushbutton158_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Bayesian model reduction and empirical Bayes for group (DCM) studies';
            run_demo_Callback(app, hObject, handles, 'DEMO_GROUP_PEB')
        end

        % Button pushed function: pushbutton159
        function pushbutton159_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Anatomically informed basis functions';
            run_demo_Callback(app, hObject, handles, 'DEM_spatial_deconvolution')
        end

        % Button pushed function: pushbutton15
        function pushbutton15_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Cortical circuits for perceptual inference';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_song_priors')
        end

        % Button pushed function: pushbutton161
        function pushbutton161_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active inference and learning';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_habits')
        end

        % Button pushed function: pushbutton163
        function pushbutton163_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_X')
        end

        % Button pushed function: pushbutton168
        function pushbutton168_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Scene Construction,Visual Foraging and Active Inference';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_search')
        end

        % Button pushed function: pushbutton169
        function pushbutton169_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_reading')
        end

        % Button pushed function: pushbutton170
        function pushbutton170_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference Curiosity and Insight';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_rule')
        end

        % Button pushed function: pushbutton197
        function pushbutton197_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Empirical Bayes for DCM A Group Inversion Scheme';
            run_demo_Callback(app, hObject, handles, 'DEMO_BMR_PEB')
        end

        % Button pushed function: pushbutton198
        function pushbutton198_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Empirical Bayes for DCM A Group Inversion Scheme';
            run_demo_Callback(app, hObject, handles, 'DEMO_GROUP_PEB')
        end

        % Button pushed function: pushbutton199
        function pushbutton199_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Bayesian model reduction and empirical Bayes for group (DCM) studies';
            run_demo_Callback(app, hObject, handles, 'DEMO_DCM_PEB')
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Variational free energy and the Laplace approximation';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_GLM')
        end

        % Button pushed function: pushbutton200
        function pushbutton200_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Bayesian model reduction and empirical Bayes for group (DCM) studies';
            run_demo_Callback(app, hObject, handles, 'DEMO_DCM_PEB_FIT')
        end

        % Button pushed function: pushbutton201
        function pushbutton201_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Comparing dynamic causal models';
            run_demo_Callback(app, hObject, handles, 'DEMO_BAYES_FACTORS')
        end

        % Button pushed function: pushbutton202
        function pushbutton202_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = '';
            run_demo_Callback(app, hObject, handles, 'DEMO_Lindley_paradox')
        end

        % Button pushed function: pushbutton210
        function pushbutton210_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Bayesian model reduction and empirical Bayes for group (DCM) studies';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_fMRI_PEB')
        end

        % Button pushed function: pushbutton211
        function pushbutton211_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active inference and epistemic value';
            run_demo_Callback(app, hObject, handles, 'DEM_MDP_decision')
        end

        % Button pushed function: pushbutton212
        function pushbutton212_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'The graphical brain';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_DEM')
        end

        % Button pushed function: pushbutton214
        function pushbutton214_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Planning and navigation as active inference';
            run_demo_Callback(app, hObject, handles, 'DEMO_MDP_maze')
        end

        % Button pushed function: pushbutton215
        function pushbutton215_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'FEP_fluctuations')
        end

        % Button pushed function: pushbutton216
        function pushbutton216_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'FEP_MB_demo')
        end

        % Button pushed function: pushbutton217
        function pushbutton217_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Knowing ones place';
            run_demo_Callback(app, hObject, handles, 'DEM_cells_cells')
        end

        % Button pushed function: pushbutton218
        function pushbutton218_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Knowing ones place';
            run_demo_Callback(app, hObject, handles, 'DEM_cells')
        end

        % Button pushed function: pushbutton219
        function pushbutton219_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'DEM_self_entropy')
        end

        % Button pushed function: pushbutton221
        function pushbutton221_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'DEM_HB_and_LE')
        end

        % Button pushed function: pushbutton222
        function pushbutton222_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'FEP_physics')
        end

        % Button pushed function: pushbutton223
        function pushbutton223_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'FEP_self_entropy')
        end

        % Button pushed function: pushbutton224
        function pushbutton224_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Post hoc Bayesian model selection';
            run_demo_Callback(app, hObject, handles, 'DEMO_AI_NLSI')
        end

        % Button pushed function: pushbutton225
        function pushbutton225_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active inference and the anatomy of oculomotion';
            run_demo_Callback(app, hObject, handles, 'MDP_DEM_Oculomotion_demo')
        end

        % Button pushed function: pushbutton226
        function pushbutton226_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'DEMO_MDP_questions')
        end

        % Button pushed function: pushbutton227
        function pushbutton227_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'A Multivariate Analysis of PET Activation Studies';
            run_demo_Callback(app, hObject, handles, 'DEMO_CVA_RSA')
        end

        % Button pushed function: pushbutton228
        function pushbutton228_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Planning and navigation as active inference';
            run_demo_Callback(app, hObject, handles, 'DEMO_niche_construction')
        end

        % Button pushed function: pushbutton229
        function pushbutton229_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_coupled_oscillators')
        end

        % Button pushed function: pushbutton230
        function pushbutton230_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'KLDemo')
        end

        % Button pushed function: pushbutton231
        function pushbutton231_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'DEM_FEP_Lorenz')
        end

        % Button pushed function: pushbutton232
        function pushbutton232_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Life as we know it';
            run_demo_Callback(app, hObject, handles, 'DEM_FEP_Least_Action')
        end

        % Button pushed function: pushbutton234
        function pushbutton234_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Interoceptive inference';
            run_demo_Callback(app, hObject, handles, 'MDP_Heart_Beat')
        end

        % Button pushed function: pushbutton239
        function pushbutton239_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'spm_voice')
        end

        % Button pushed function: pushbutton240
        function pushbutton240_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'spm_voice_P300')
        end

        % Button pushed function: pushbutton241
        function pushbutton241_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'spm_voice_repeat')
        end

        % Button pushed function: pushbutton242
        function pushbutton242_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'spm_voice_read')
        end

        % Button pushed function: pushbutton243
        function pushbutton243_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'DEMO_MDP_voice')
        end

        % Button pushed function: pushbutton244
        function pushbutton244_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Deep temporal models and active inference';
            run_demo_Callback(app, hObject, handles, 'MDP_DEM_Mixed_Models_Movement')
        end

        % Button pushed function: pushbutton245
        function pushbutton245_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'A free energy principle for a particular physics';
            run_demo_Callback(app, hObject, handles, 'DEMO_DCM_MB')
        end

        % Button pushed function: pushbutton247
        function pushbutton247_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'A free energy principle for a particular physics';
            run_demo_Callback(app, hObject, handles, 'Markov_blankets_and_NESS')
        end

        % Button pushed function: pushbutton248
        function pushbutton248_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Observing the Observer I';
            run_demo_Callback(app, hObject, handles, 'spm_meta_model')
        end

        % Button pushed function: pushbutton249
        function pushbutton249_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Computational Phenotyping in Psychiatry';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_fit')
        end

        % Button pushed function: pushbutton250
        function pushbutton250_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Computational Nosology';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_ontology')
        end

        % Button pushed function: pushbutton251
        function pushbutton251_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Computational Phenotyping in Psychiatry';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_fit_fields')
        end

        % Button pushed function: pushbutton252
        function pushbutton252_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            run_demo_Callback(app, hObject, handles, 'DEM_COVID_UK4')
        end

        % Button pushed function: pushbutton258
        function pushbutton258_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MDP_XX')
        end

        % Button pushed function: pushbutton259
        function pushbutton259_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Planning and navigation as active inference';
            run_demo_Callback(app, hObject, handles, 'DEMO_MDP_maze_X')
        end

        % Button pushed function: pushbutton260
        function pushbutton260_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'A free energy principle for a particular physics';
            run_demo_Callback(app, hObject, handles, 'FEP_lorenz_surprise')
        end

        % Button pushed function: pushbutton261
        function pushbutton261_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Computational Nosology';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_psychosis')
        end

        % Button pushed function: pushbutton262
        function pushbutton262_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            run_demo_Callback(app, hObject, handles, 'DEM_CLIMATE_India')
        end

        % Button pushed function: pushbutton263
        function pushbutton263_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_surveillance')
        end

        % Button pushed function: pushbutton264
        function pushbutton264_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'A free energy principle for a particular physics';
            run_demo_Callback(app, hObject, handles, 'FEP_information_length')
        end

        % Button pushed function: pushbutton265
        function pushbutton265_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_federation')
        end

        % Button pushed function: pushbutton266
        function pushbutton266_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Variational free energy and the Laplace approximation';
            run_demo_Callback(app, hObject, handles, 'DEM_psychophysics')
        end

        % Button pushed function: pushbutton267
        function pushbutton267_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_dSprites')
        end

        % Button pushed function: pushbutton268
        function pushbutton268_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_Tower_of_Hanoi')
        end

        % Button pushed function: pushbutton269
        function pushbutton269_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_MNIST')
        end

        % Button pushed function: pushbutton282
        function pushbutton282_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEMO_MDP_maze_XXX')
        end

        % Button pushed function: pushbutton283
        function pushbutton283_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_Tower')
        end

        % Button pushed function: pushbutton284
        function pushbutton284_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_Pong')
        end

        % Button pushed function: pushbutton285
        function pushbutton285_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_MNIST_conv')
        end

        % Button pushed function: pushbutton286
        function pushbutton286_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_drone_V')
        end

        % Button pushed function: pushbutton287
        function pushbutton287_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_AtariIII')
        end

        % Button pushed function: pushbutton288
        function pushbutton288_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_AtariIV')
        end

        % Button pushed function: pushbutton289
        function pushbutton289_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_compression')
        end

        % Button pushed function: pushbutton290
        function pushbutton290_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_sound_compression')
        end

        % Button pushed function: pushbutton291
        function pushbutton291_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_image_compression')
        end

        % Button pushed function: pushbutton292
        function pushbutton292_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_drone_vision')
        end

        % Button pushed function: pushbutton293
        function pushbutton293_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_Tower')
        end

        % Button pushed function: pushbutton294
        function pushbutton294_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_video_compression')
        end

        % Button pushed function: pushbutton295
        function pushbutton295_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_MNIST_mixture')
        end

        % Button pushed function: pushbutton296
        function pushbutton296_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_drone_telemetry')
        end

        % Button pushed function: pushbutton297
        function pushbutton297_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'ADEM_physics')
        end

        % Button pushed function: pushbutton298
        function pushbutton298_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_music_compression')
        end

        % Button pushed function: pushbutton299
        function pushbutton299_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Dynamic causal modelling';
            run_demo_Callback(app, hObject, handles, 'DEM_Diabetes')
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Variational free energy and the Laplace approximation';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_PEB')
        end

        % Button pushed function: pushbutton300
        function pushbutton300_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_laws')
        end

        % Button pushed function: pushbutton301
        function pushbutton301_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Knowing ones place';
            run_demo_Callback(app, hObject, handles, 'DEM_swarm')
        end

        % Button pushed function: pushbutton302
        function pushbutton302_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference Curiosity and Insight';
            run_demo_Callback(app, hObject, handles, 'DEM_reasoning')
        end

        % Button pushed function: pushbutton306
        function pushbutton306_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_ness_Lap')
        end

        % Button pushed function: pushbutton307
        function pushbutton307_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active Inference A Process Theory';
            run_demo_Callback(app, hObject, handles, 'DEM_metric_BMR')
        end

        % Button pushed function: pushbutton308
        function pushbutton308_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_FIN')
        end

        % Button pushed function: pushbutton309
        function pushbutton309_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Active inference, eye movements and oculomotor delays';
            run_demo_Callback(app, hObject, handles, 'ADEM_intercept')
        end

        % Button pushed function: pushbutton37
        function pushbutton37_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Predictive coding under the free-energy principle';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_song_inference')
        end

        % Button pushed function: pushbutton38
        function pushbutton38_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Cortical circuits for perceptual inference';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_song_omission')
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Variational filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_DFP')
        end

        % Button pushed function: pushbutton40
        function pushbutton40_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Learning and inference in the brain';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_face_inference')
        end

        % Button pushed function: pushbutton41
        function pushbutton41_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Attractors in song';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_MMN')
        end

        % Button pushed function: pushbutton42
        function pushbutton42_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = '';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_Gabor')
        end

        % Button pushed function: pushbutton44
        function pushbutton44_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Action and behavior A free-energy formulation';
            run_demo_Callback(app, hObject, handles, 'ADEM_visual')
        end

        % Button pushed function: pushbutton45
        function pushbutton45_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Action and behavior A free-energy formulation';
            run_demo_Callback(app, hObject, handles, 'ADEM_motor')
        end

        % Button pushed function: pushbutton46
        function pushbutton46_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Reinforcement Learning or Active Inference';
            run_demo_Callback(app, hObject, handles, 'ADEM_learning')
        end

        % Button pushed function: pushbutton47
        function pushbutton47_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Action and behavior A free-energy formulation';
            run_demo_Callback(app, hObject, handles, 'ADEM_lorenz')
        end

        % Button pushed function: pushbutton48
        function pushbutton48_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Action and behavior A free-energy formulation';
            run_demo_Callback(app, hObject, handles, 'ADEM_reaching')
        end

        % Button pushed function: pushbutton49
        function pushbutton49_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Free Energy Value and Attractors';
            run_demo_Callback(app, hObject, handles, 'ADEM_lorenz_entropy')
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Hierarchical Models in the Brain';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_factor_analysis')
        end

        % Button pushed function: pushbutton50
        function pushbutton50_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Free Energy Value and Attractors';
            run_demo_Callback(app, hObject, handles, 'ADEM_mountaincar_loss')
        end

        % Button pushed function: pushbutton51
        function pushbutton51_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            set(handles.pushbutton51,'String','please wait')
            if isempty(spm_figure('FindWin',1))
                spm_figure('GetWin','DEM');
            end
            
            feval(handles.file)
            
            set(handles.pushbutton51,'String','run demo')
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'DEM A variational treatment of dynamic systems';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_OU')
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Hierarchical Models in the Brain';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_convolution')
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'DEM A variational treatment of dynamic systems';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_EM')
        end

        % Button pushed function: pushbutton80
        function pushbutton80_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Policies and Priors';
            run_demo_Callback(app, hObject, handles, 'ADEM_SHC_demo')
        end

        % Button pushed function: pushbutton81
        function pushbutton81_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Free Energy Value and Attractors';
            run_demo_Callback(app, hObject, handles, 'ADEM_lorenz_surprise')
        end

        % Button pushed function: pushbutton83
        function pushbutton83_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_LAP')
        end

        % Button pushed function: pushbutton84
        function pushbutton84_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Generalised Filtering';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_hdm_LAP')
        end

        % Button pushed function: pushbutton8
        function pushbutton8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'DEM A variational treatment of dynamic systems';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_DEM')
        end

        % Button pushed function: pushbutton91
        function pushbutton91_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Attention uncertainty and free-energy';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_Posner')
        end

        % Button pushed function: pushbutton92
        function pushbutton92_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Policies and Priors';
            run_demo_Callback(app, hObject, handles, 'ADEM_cost_SHC')
        end

        % Button pushed function: pushbutton93
        function pushbutton93_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
                if isdeployed
                    set(hObject,'String','MATLAB code (not available)');
                    set(hObject,'Enable','Off');
                else
                    edit(handles.file);
                end
            end
        end

        % Button pushed function: pushbutton94
        function pushbutton94_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = '';
            run_demo_Callback(app, hObject, handles, '%%%% DEM_demo_CM_Lorenz %%%')
        end

        % Button pushed function: pushbutton96
        function pushbutton96_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Action understanding and active inference';
            run_demo_Callback(app, hObject, handles, 'ADEM_observe')
        end

        % Button pushed function: pushbutton97
        function pushbutton97_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'Network discovery with DCM';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_DCM_LAP')
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'DEM A variational treatment of dynamic systems';
            run_demo_Callback(app, hObject, handles, 'DEM_demo_filtering')
        end

        % Button pushed function: pushbutton219_2
        function pushbutton219_2ButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.web = 'DEM A variational treatment of dynamic systems';
            run_demo_Callback(app, hObject, handles, 'DEM_paths')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'DEM_demo';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Generalised filtering, Active inference and Free-energy';
            app.uipanel2.Tag = 'uipanel2';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Static Models';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'General Linear Model';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Emprical Bayes';
            app.pushbutton4 = uibutton(app.uipanel1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Factor Analysis';
            app.pushbutton150 = uibutton(app.uipanel1, 'push');
            app.pushbutton150.ButtonPushedFcn = createCallbackFcn(app, @pushbutton150_Callback, true);
            app.pushbutton150.Tag = 'pushbutton150';
            app.pushbutton150.Text = 'Figure-ground';
            app.pushbutton156 = uibutton(app.uipanel1, 'push');
            app.pushbutton156.ButtonPushedFcn = createCallbackFcn(app, @pushbutton156_Callback, true);
            app.pushbutton156.Tag = 'pushbutton156';
            app.pushbutton156.Text = 'Sparse regression';
            app.pushbutton157 = uibutton(app.uipanel1, 'push');
            app.pushbutton157.ButtonPushedFcn = createCallbackFcn(app, @pushbutton157_Callback, true);
            app.pushbutton157.Tag = 'pushbutton157';
            app.pushbutton157.Text = 'PEB with BMR';
            app.pushbutton158 = uibutton(app.uipanel1, 'push');
            app.pushbutton158.ButtonPushedFcn = createCallbackFcn(app, @pushbutton158_Callback, true);
            app.pushbutton158.Tag = 'pushbutton158';
            app.pushbutton158.Text = '+';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Variational Filtering';
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton14 = uibutton(app.uipanel4, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @pushbutton14_Callback, true);
            app.pushbutton14.Tag = 'pushbutton14';
            app.pushbutton14.Text = 'Double-well';
            app.pushbutton3 = uibutton(app.uipanel4, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Linear deconvolution';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Overview';
            app.uipanel5.Tag = 'uipanel5';
            app.help = uilabel(app.uipanel5);
            app.help.Tag = 'help';
            app.help.Text = '';
            app.pushbutton51 = uibutton(app.uipanel5, 'push');
            app.pushbutton51.ButtonPushedFcn = createCallbackFcn(app, @pushbutton51_Callback, true);
            app.pushbutton51.Tag = 'pushbutton51';
            app.pushbutton51.Text = 'Run demo';
            app.text3 = uilabel(app.uipanel5);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Copyright (c) 2024 Karl Friston';
            app.pushbutton93 = uibutton(app.uipanel5, 'push');
            app.pushbutton93.ButtonPushedFcn = createCallbackFcn(app, @pushbutton93_Callback, true);
            app.pushbutton93.Tag = 'pushbutton93';
            app.pushbutton93.Text = 'Matlab code';
            app.pushbutton131 = uibutton(app.uipanel5, 'push');
            app.pushbutton131.ButtonPushedFcn = createCallbackFcn(app, @pushbutton131_Callback, true);
            app.pushbutton131.Tag = 'pushbutton131';
            app.pushbutton131.Text = 'PDF';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Active inference (continuous)';
            app.uipanel9.Tag = 'uipanel9';
            app.pushbutton44 = uibutton(app.uipanel9, 'push');
            app.pushbutton44.ButtonPushedFcn = createCallbackFcn(app, @pushbutton44_Callback, true);
            app.pushbutton44.Tag = 'pushbutton44';
            app.pushbutton44.Text = 'Visual tracking';
            app.pushbutton45 = uibutton(app.uipanel9, 'push');
            app.pushbutton45.ButtonPushedFcn = createCallbackFcn(app, @pushbutton45_Callback, true);
            app.pushbutton45.Tag = 'pushbutton45';
            app.pushbutton45.Text = 'Motor trajectories';
            app.pushbutton46 = uibutton(app.uipanel9, 'push');
            app.pushbutton46.ButtonPushedFcn = createCallbackFcn(app, @pushbutton46_Callback, true);
            app.pushbutton46.Tag = 'pushbutton46';
            app.pushbutton46.Text = 'Mountain car';
            app.pushbutton48 = uibutton(app.uipanel9, 'push');
            app.pushbutton48.ButtonPushedFcn = createCallbackFcn(app, @pushbutton48_Callback, true);
            app.pushbutton48.Tag = 'pushbutton48';
            app.pushbutton48.Text = 'Reaching';
            app.pushbutton47 = uibutton(app.uipanel9, 'push');
            app.pushbutton47.ButtonPushedFcn = createCallbackFcn(app, @pushbutton47_Callback, true);
            app.pushbutton47.Tag = 'pushbutton47';
            app.pushbutton47.Text = 'Attractor dynamics';
            app.pushbutton102 = uibutton(app.uipanel9, 'push');
            app.pushbutton102.ButtonPushedFcn = createCallbackFcn(app, @pushbutton102_Callback, true);
            app.pushbutton102.Tag = 'pushbutton102';
            app.pushbutton102.Text = 'Writing';
            app.uipanel15 = uipanel(app.figure1);
            app.uipanel15.Title = 'Behaviour and learning (dynamic)';
            app.uipanel15.Tag = 'uipanel15';
            app.pushbutton80 = uibutton(app.uipanel15, 'push');
            app.pushbutton80.ButtonPushedFcn = createCallbackFcn(app, @pushbutton80_Callback, true);
            app.pushbutton80.Tag = 'pushbutton80';
            app.pushbutton80.Text = 'Heteroclinic channels';
            app.pushbutton50 = uibutton(app.uipanel15, 'push');
            app.pushbutton50.ButtonPushedFcn = createCallbackFcn(app, @pushbutton50_Callback, true);
            app.pushbutton50.Tag = 'pushbutton50';
            app.pushbutton50.Text = 'Cost and divergence';
            app.pushbutton92 = uibutton(app.uipanel15, 'push');
            app.pushbutton92.ButtonPushedFcn = createCallbackFcn(app, @pushbutton92_Callback, true);
            app.pushbutton92.Tag = 'pushbutton92';
            app.pushbutton92.Text = 'Addiction and SHC';
            app.pushbutton117 = uibutton(app.uipanel15, 'push');
            app.pushbutton117.ButtonPushedFcn = createCallbackFcn(app, @pushbutton117_Callback, true);
            app.pushbutton117.Tag = 'pushbutton117';
            app.pushbutton117.Text = 'Affordance and cues';
            app.pushbutton121 = uibutton(app.uipanel15, 'push');
            app.pushbutton121.ButtonPushedFcn = createCallbackFcn(app, @pushbutton121_Callback, true);
            app.pushbutton121.Tag = 'pushbutton121';
            app.pushbutton121.Text = 'Mountain car (MDP)';
            app.pushbutton155 = uibutton(app.uipanel15, 'push');
            app.pushbutton155.ButtonPushedFcn = createCallbackFcn(app, @pushbutton155_Callback, true);
            app.pushbutton155.Tag = 'pushbutton155';
            app.pushbutton155.Text = 'Eyeblink conditioning';
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Title = 'Self-organisation and dynamics';
            app.uipanel16.Tag = 'uipanel16';
            app.pushbutton49 = uibutton(app.uipanel16, 'push');
            app.pushbutton49.ButtonPushedFcn = createCallbackFcn(app, @pushbutton49_Callback, true);
            app.pushbutton49.Tag = 'pushbutton49';
            app.pushbutton49.Text = 'Learning and entropy';
            app.pushbutton81 = uibutton(app.uipanel16, 'push');
            app.pushbutton81.ButtonPushedFcn = createCallbackFcn(app, @pushbutton81_Callback, true);
            app.pushbutton81.Tag = 'pushbutton81';
            app.pushbutton81.Text = 'Loss & surprise';
            app.pushbutton123 = uibutton(app.uipanel16, 'push');
            app.pushbutton123.ButtonPushedFcn = createCallbackFcn(app, @pushbutton123_Callback, true);
            app.pushbutton123.Tag = 'pushbutton123';
            app.pushbutton123.Text = 'Criticality and slowing';
            app.pushbutton132 = uibutton(app.uipanel16, 'push');
            app.pushbutton132.ButtonPushedFcn = createCallbackFcn(app, @pushbutton132_Callback, true);
            app.pushbutton132.Tag = 'pushbutton132';
            app.pushbutton132.Text = 'Life as we know it';
            app.pushbutton103 = uibutton(app.uipanel16, 'push');
            app.pushbutton103.ButtonPushedFcn = createCallbackFcn(app, @pushbutton103_Callback, true);
            app.pushbutton103.Tag = 'pushbutton103';
            app.pushbutton103.Text = 'Synaptic selection';
            app.pushbutton215 = uibutton(app.uipanel16, 'push');
            app.pushbutton215.ButtonPushedFcn = createCallbackFcn(app, @pushbutton215_Callback, true);
            app.pushbutton215.Tag = 'pushbutton215';
            app.pushbutton215.Text = 'Sentient physics';
            app.pushbutton216 = uibutton(app.uipanel16, 'push');
            app.pushbutton216.ButtonPushedFcn = createCallbackFcn(app, @pushbutton216_Callback, true);
            app.pushbutton216.Tag = 'pushbutton216';
            app.pushbutton216.Text = 'Markov Blankets';
            app.pushbutton217 = uibutton(app.uipanel16, 'push');
            app.pushbutton217.ButtonPushedFcn = createCallbackFcn(app, @pushbutton217_Callback, true);
            app.pushbutton217.Tag = 'pushbutton217';
            app.pushbutton217.Text = 'Cells of cells';
            app.pushbutton218 = uibutton(app.uipanel16, 'push');
            app.pushbutton218.ButtonPushedFcn = createCallbackFcn(app, @pushbutton218_Callback, true);
            app.pushbutton218.Tag = 'pushbutton218';
            app.pushbutton218.Text = '+';
            app.pushbutton219 = uibutton(app.uipanel16, 'push');
            app.pushbutton219.ButtonPushedFcn = createCallbackFcn(app, @pushbutton219_Callback, true);
            app.pushbutton219.Tag = 'pushbutton219';
            app.pushbutton219.Text = 'Self entropy';
            app.pushbutton221 = uibutton(app.uipanel16, 'push');
            app.pushbutton221.ButtonPushedFcn = createCallbackFcn(app, @pushbutton221_Callback, true);
            app.pushbutton221.Tag = 'pushbutton221';
            app.pushbutton221.Text = 'Entropy and chaos';
            app.pushbutton222 = uibutton(app.uipanel16, 'push');
            app.pushbutton222.ButtonPushedFcn = createCallbackFcn(app, @pushbutton222_Callback, true);
            app.pushbutton222.Tag = 'pushbutton222';
            app.pushbutton222.Text = 'Physics & blankets';
            app.pushbutton223 = uibutton(app.uipanel16, 'push');
            app.pushbutton223.ButtonPushedFcn = createCallbackFcn(app, @pushbutton223_Callback, true);
            app.pushbutton223.Tag = 'pushbutton223';
            app.pushbutton223.Text = 'Self organisation';
            app.pushbutton231 = uibutton(app.uipanel16, 'push');
            app.pushbutton231.ButtonPushedFcn = createCallbackFcn(app, @pushbutton231_Callback, true);
            app.pushbutton231.Tag = 'pushbutton231';
            app.pushbutton231.Text = 'Deterministic chaos';
            app.pushbutton232 = uibutton(app.uipanel16, 'push');
            app.pushbutton232.ButtonPushedFcn = createCallbackFcn(app, @pushbutton232_Callback, true);
            app.pushbutton232.Tag = 'pushbutton232';
            app.pushbutton232.Text = 'Paths of least action';
            app.pushbutton247 = uibutton(app.uipanel16, 'push');
            app.pushbutton247.ButtonPushedFcn = createCallbackFcn(app, @pushbutton247_Callback, true);
            app.pushbutton247.Tag = 'pushbutton247';
            app.pushbutton247.Text = 'Blankets & NESS';
            app.pushbutton260 = uibutton(app.uipanel16, 'push');
            app.pushbutton260.ButtonPushedFcn = createCallbackFcn(app, @pushbutton260_Callback, true);
            app.pushbutton260.Tag = 'pushbutton260';
            app.pushbutton260.Text = 'Stochastic chaos';
            app.pushbutton264 = uibutton(app.uipanel16, 'push');
            app.pushbutton264.ButtonPushedFcn = createCallbackFcn(app, @pushbutton264_Callback, true);
            app.pushbutton264.Tag = 'pushbutton264';
            app.pushbutton264.Text = 'Information length';
            app.pushbutton306 = uibutton(app.uipanel16, 'push');
            app.pushbutton306.ButtonPushedFcn = createCallbackFcn(app, @pushbutton306_Callback, true);
            app.pushbutton306.Tag = 'pushbutton306';
            app.pushbutton306.Text = 'Forecasting';
            app.pushbutton308 = uibutton(app.uipanel16, 'push');
            app.pushbutton308.ButtonPushedFcn = createCallbackFcn(app, @pushbutton308_Callback, true);
            app.pushbutton308.Tag = 'pushbutton308';
            app.pushbutton308.Enable = 'off';
            app.pushbutton308.Text = '+';
            app.pushbutton219_2 = uibutton(app.uipanel16, 'push');
            app.pushbutton219_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton219_2ButtonPushed, true);
            app.pushbutton219_2.Tag = 'pushbutton219';
            app.pushbutton219_2.Text = '+';
            app.uipanel17 = uipanel(app.figure1);
            app.uipanel17.Title = 'Physiological models';
            app.uipanel17.Tag = 'uipanel17';
            app.pushbutton11 = uibutton(app.uipanel17, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = 'Hemodynamics';
            app.pushbutton229 = uibutton(app.uipanel17, 'push');
            app.pushbutton229.ButtonPushedFcn = createCallbackFcn(app, @pushbutton229_Callback, true);
            app.pushbutton229.Tag = 'pushbutton229';
            app.pushbutton229.Text = 'Coupled oscillators';
            app.pushbutton245 = uibutton(app.uipanel17, 'push');
            app.pushbutton245.ButtonPushedFcn = createCallbackFcn(app, @pushbutton245_Callback, true);
            app.pushbutton245.Tag = 'pushbutton245';
            app.pushbutton245.Text = 'DCM & blankets';
            app.pushbutton159 = uibutton(app.uipanel17, 'push');
            app.pushbutton159.ButtonPushedFcn = createCallbackFcn(app, @pushbutton159_Callback, true);
            app.pushbutton159.Tag = 'pushbutton159';
            app.pushbutton159.Text = 'Image deconvolution';
            app.uipanel19 = uipanel(app.figure1);
            app.uipanel19.Title = 'Generalised Filtering';
            app.uipanel19.Tag = 'uipanel19';
            app.pushbutton83 = uibutton(app.uipanel19, 'push');
            app.pushbutton83.ButtonPushedFcn = createCallbackFcn(app, @pushbutton83_Callback, true);
            app.pushbutton83.Tag = 'pushbutton83';
            app.pushbutton83.Text = 'Triple estimation';
            app.pushbutton84 = uibutton(app.uipanel19, 'push');
            app.pushbutton84.ButtonPushedFcn = createCallbackFcn(app, @pushbutton84_Callback, true);
            app.pushbutton84.Tag = 'pushbutton84';
            app.pushbutton84.Text = 'Hemodynamics';
            app.pushbutton94 = uibutton(app.uipanel19, 'push');
            app.pushbutton94.ButtonPushedFcn = createCallbackFcn(app, @pushbutton94_Callback, true);
            app.pushbutton94.Tag = 'pushbutton94';
            app.pushbutton94.Enable = 'off';
            app.pushbutton94.Text = 'Phase-reduction';
            app.pushbutton126 = uibutton(app.uipanel19, 'push');
            app.pushbutton126.ButtonPushedFcn = createCallbackFcn(app, @pushbutton126_Callback, true);
            app.pushbutton126.Tag = 'pushbutton126';
            app.pushbutton126.Text = '+';
            app.pushbutton146 = uibutton(app.uipanel19, 'push');
            app.pushbutton146.ButtonPushedFcn = createCallbackFcn(app, @pushbutton146_Callback, true);
            app.pushbutton146.Tag = 'pushbutton146';
            app.pushbutton146.Text = 'Cubature filtering';
            app.uipanel23 = uipanel(app.figure1);
            app.uipanel23.Title = 'Cognitive neuroscience (continuous states)';
            app.uipanel23.Tag = 'uipanel23';
            app.pushbutton91 = uibutton(app.uipanel23, 'push');
            app.pushbutton91.ButtonPushedFcn = createCallbackFcn(app, @pushbutton91_Callback, true);
            app.pushbutton91.Tag = 'pushbutton91';
            app.pushbutton91.Text = 'Biased competition';
            app.pushbutton96 = uibutton(app.uipanel23, 'push');
            app.pushbutton96.ButtonPushedFcn = createCallbackFcn(app, @pushbutton96_Callback, true);
            app.pushbutton96.Tag = 'pushbutton96';
            app.pushbutton96.Text = 'Action-observation';
            app.pushbutton104 = uibutton(app.uipanel23, 'push');
            app.pushbutton104.ButtonPushedFcn = createCallbackFcn(app, @pushbutton104_Callback, true);
            app.pushbutton104.Tag = 'pushbutton104';
            app.pushbutton104.Text = 'Cornsweet illusion';
            app.pushbutton105 = uibutton(app.uipanel23, 'push');
            app.pushbutton105.ButtonPushedFcn = createCallbackFcn(app, @pushbutton105_Callback, true);
            app.pushbutton105.Tag = 'pushbutton105';
            app.pushbutton105.Text = 'Slow pursuit';
            app.pushbutton118 = uibutton(app.uipanel23, 'push');
            app.pushbutton118.ButtonPushedFcn = createCallbackFcn(app, @pushbutton118_Callback, true);
            app.pushbutton118.Tag = 'pushbutton118';
            app.pushbutton118.Text = 'MMN & latency';
            app.pushbutton122 = uibutton(app.uipanel23, 'push');
            app.pushbutton122.ButtonPushedFcn = createCallbackFcn(app, @pushbutton122_Callback, true);
            app.pushbutton122.Tag = 'pushbutton122';
            app.pushbutton122.Text = 'Visual search';
            app.pushbutton124 = uibutton(app.uipanel23, 'push');
            app.pushbutton124.ButtonPushedFcn = createCallbackFcn(app, @pushbutton124_Callback, true);
            app.pushbutton124.Tag = 'pushbutton124';
            app.pushbutton124.Text = 'Occulomotor delays';
            app.pushbutton125 = uibutton(app.uipanel23, 'push');
            app.pushbutton125.ButtonPushedFcn = createCallbackFcn(app, @pushbutton125_Callback, true);
            app.pushbutton125.Tag = 'pushbutton125';
            app.pushbutton125.Text = 'Visual occlusion';
            app.pushbutton127 = uibutton(app.uipanel23, 'push');
            app.pushbutton127.ButtonPushedFcn = createCallbackFcn(app, @pushbutton127_Callback, true);
            app.pushbutton127.Tag = 'pushbutton127';
            app.pushbutton127.Text = 'Sensory attenuation';
            app.pushbutton129 = uibutton(app.uipanel23, 'push');
            app.pushbutton129.ButtonPushedFcn = createCallbackFcn(app, @pushbutton129_Callback, true);
            app.pushbutton129.Tag = 'pushbutton129';
            app.pushbutton129.Text = 'Evidence accumulation';
            app.uipanel24 = uipanel(app.figure1);
            app.uipanel24.Title = 'Variational Laplace and dynamic causal modelling';
            app.uipanel24.Tag = 'uipanel24';
            app.pushbutton97 = uibutton(app.uipanel24, 'push');
            app.pushbutton97.ButtonPushedFcn = createCallbackFcn(app, @pushbutton97_Callback, true);
            app.pushbutton97.Tag = 'pushbutton97';
            app.pushbutton97.Text = 'Stochastic DCM (fMRI)';
            app.pushbutton134 = uibutton(app.uipanel24, 'push');
            app.pushbutton134.ButtonPushedFcn = createCallbackFcn(app, @pushbutton134_Callback, true);
            app.pushbutton134.Tag = 'pushbutton134';
            app.pushbutton134.Text = 'Spectral DCM for fMRI';
            app.pushbutton143 = uibutton(app.uipanel24, 'push');
            app.pushbutton143.ButtonPushedFcn = createCallbackFcn(app, @pushbutton143_Callback, true);
            app.pushbutton143.Tag = 'pushbutton143';
            app.pushbutton143.Text = 'Large DCM for fMRI';
            app.pushbutton144 = uibutton(app.uipanel24, 'push');
            app.pushbutton144.ButtonPushedFcn = createCallbackFcn(app, @pushbutton144_Callback, true);
            app.pushbutton144.Tag = 'pushbutton144';
            app.pushbutton144.Text = 'Emprical Bayes (DCM)';
            app.pushbutton145 = uibutton(app.uipanel24, 'push');
            app.pushbutton145.ButtonPushedFcn = createCallbackFcn(app, @pushbutton145_Callback, true);
            app.pushbutton145.Tag = 'pushbutton145';
            app.pushbutton145.Text = 'Eigenmodes & DCM';
            app.pushbutton153 = uibutton(app.uipanel24, 'push');
            app.pushbutton153.ButtonPushedFcn = createCallbackFcn(app, @pushbutton153_Callback, true);
            app.pushbutton153.Tag = 'pushbutton153';
            app.pushbutton153.Text = 'Bayesian reduction';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Dynamic Models';
            app.uipanel3.Tag = 'uipanel3';
            app.pushbutton5 = uibutton(app.uipanel3, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'Ornstein-Uhlenbeck';
            app.pushbutton6 = uibutton(app.uipanel3, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = 'Deconvolution';
            app.pushbutton7 = uibutton(app.uipanel3, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'Dual estimation';
            app.pushbutton8 = uibutton(app.uipanel3, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = 'Triple estimation';
            app.pushbutton9 = uibutton(app.uipanel3, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = 'Bayesian filtering';
            app.pushbutton10 = uibutton(app.uipanel3, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = 'Lorenz attractor';
            app.pushbutton12 = uibutton(app.uipanel3, 'push');
            app.pushbutton12.ButtonPushedFcn = createCallbackFcn(app, @pushbutton12_Callback, true);
            app.pushbutton12.Tag = 'pushbutton12';
            app.pushbutton12.Text = 'Double-well';
            app.pushbutton120 = uibutton(app.uipanel3, 'push');
            app.pushbutton120.ButtonPushedFcn = createCallbackFcn(app, @pushbutton120_Callback, true);
            app.pushbutton120.Tag = 'pushbutton120';
            app.pushbutton120.Text = 'DEM & Kalman filtering';
            app.pushbutton147 = uibutton(app.uipanel3, 'push');
            app.pushbutton147.ButtonPushedFcn = createCallbackFcn(app, @pushbutton147_Callback, true);
            app.pushbutton147.Tag = 'pushbutton147';
            app.pushbutton147.Text = '+';
            app.pushbutton148 = uibutton(app.uipanel3, 'push');
            app.pushbutton148.ButtonPushedFcn = createCallbackFcn(app, @pushbutton148_Callback, true);
            app.pushbutton148.Tag = 'pushbutton148';
            app.pushbutton148.Text = '+';
            app.pushbutton149 = uibutton(app.uipanel3, 'push');
            app.pushbutton149.ButtonPushedFcn = createCallbackFcn(app, @pushbutton149_Callback, true);
            app.pushbutton149.Tag = 'pushbutton149';
            app.pushbutton149.Text = '+';
            app.pushbutton297 = uibutton(app.uipanel3, 'push');
            app.pushbutton297.ButtonPushedFcn = createCallbackFcn(app, @pushbutton297_Callback, true);
            app.pushbutton297.Tag = 'pushbutton297';
            app.pushbutton297.Text = 'n-body problems';
            app.pushbutton119 = uibutton(app.uipanel3, 'push');
            app.pushbutton119.ButtonPushedFcn = createCallbackFcn(app, @pushbutton119_Callback, true);
            app.pushbutton119.Tag = 'pushbutton119';
            app.pushbutton119.Text = 'Contact lens';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Perceptual learning and inference';
            app.uipanel7.Tag = 'uipanel7';
            app.pushbutton15 = uibutton(app.uipanel7, 'push');
            app.pushbutton15.ButtonPushedFcn = createCallbackFcn(app, @pushbutton15_Callback, true);
            app.pushbutton15.Tag = 'pushbutton15';
            app.pushbutton15.Text = 'Bird-songs and priors';
            app.pushbutton37 = uibutton(app.uipanel7, 'push');
            app.pushbutton37.ButtonPushedFcn = createCallbackFcn(app, @pushbutton37_Callback, true);
            app.pushbutton37.Tag = 'pushbutton37';
            app.pushbutton37.Text = 'Categorisation';
            app.pushbutton38 = uibutton(app.uipanel7, 'push');
            app.pushbutton38.ButtonPushedFcn = createCallbackFcn(app, @pushbutton38_Callback, true);
            app.pushbutton38.Tag = 'pushbutton38';
            app.pushbutton38.Text = 'Omission responses';
            app.pushbutton40 = uibutton(app.uipanel7, 'push');
            app.pushbutton40.ButtonPushedFcn = createCallbackFcn(app, @pushbutton40_Callback, true);
            app.pushbutton40.Tag = 'pushbutton40';
            app.pushbutton40.Text = 'Face recogniton';
            app.pushbutton41 = uibutton(app.uipanel7, 'push');
            app.pushbutton41.ButtonPushedFcn = createCallbackFcn(app, @pushbutton41_Callback, true);
            app.pushbutton41.Tag = 'pushbutton41';
            app.pushbutton41.Text = 'Mismatch negativity';
            app.pushbutton42 = uibutton(app.uipanel7, 'push');
            app.pushbutton42.ButtonPushedFcn = createCallbackFcn(app, @pushbutton42_Callback, true);
            app.pushbutton42.Tag = 'pushbutton42';
            app.pushbutton42.Text = 'Position invariance';
            app.uipanel26 = uipanel(app.figure1);
            app.uipanel26.Title = 'Active inference';
            app.uipanel26.Tag = 'uipanel26';
            app.pushbutton130 = uibutton(app.uipanel26, 'push');
            app.pushbutton130.ButtonPushedFcn = createCallbackFcn(app, @pushbutton130_Callback, true);
            app.pushbutton130.Tag = 'pushbutton130';
            app.pushbutton130.Text = 'Waiting game';
            app.pushbutton133 = uibutton(app.uipanel26, 'push');
            app.pushbutton133.ButtonPushedFcn = createCallbackFcn(app, @pushbutton133_Callback, true);
            app.pushbutton133.Tag = 'pushbutton133';
            app.pushbutton133.Text = 'Trust games';
            app.pushbutton142 = uibutton(app.uipanel26, 'push');
            app.pushbutton142.ButtonPushedFcn = createCallbackFcn(app, @pushbutton142_Callback, true);
            app.pushbutton142.Tag = 'pushbutton142';
            app.pushbutton142.Text = 'Urn or beads task';
            app.pushbutton152 = uibutton(app.uipanel26, 'push');
            app.pushbutton152.ButtonPushedFcn = createCallbackFcn(app, @pushbutton152_Callback, true);
            app.pushbutton152.Tag = 'pushbutton152';
            app.pushbutton152.Text = 'Epistemic value';
            app.pushbutton161 = uibutton(app.uipanel26, 'push');
            app.pushbutton161.ButtonPushedFcn = createCallbackFcn(app, @pushbutton161_Callback, true);
            app.pushbutton161.Tag = 'pushbutton161';
            app.pushbutton161.Text = ' Habit learning';
            app.pushbutton163 = uibutton(app.uipanel26, 'push');
            app.pushbutton163.ButtonPushedFcn = createCallbackFcn(app, @pushbutton163_Callback, true);
            app.pushbutton163.Tag = 'pushbutton163';
            app.pushbutton163.Text = '+';
            app.pushbutton168 = uibutton(app.uipanel26, 'push');
            app.pushbutton168.ButtonPushedFcn = createCallbackFcn(app, @pushbutton168_Callback, true);
            app.pushbutton168.Tag = 'pushbutton168';
            app.pushbutton168.Text = 'Visual foraging';
            app.pushbutton169 = uibutton(app.uipanel26, 'push');
            app.pushbutton169.ButtonPushedFcn = createCallbackFcn(app, @pushbutton169_Callback, true);
            app.pushbutton169.Tag = 'pushbutton169';
            app.pushbutton169.Text = 'Reading';
            app.pushbutton170 = uibutton(app.uipanel26, 'push');
            app.pushbutton170.ButtonPushedFcn = createCallbackFcn(app, @pushbutton170_Callback, true);
            app.pushbutton170.Tag = 'pushbutton170';
            app.pushbutton170.Text = ' Artificial curiosity';
            app.pushbutton211 = uibutton(app.uipanel26, 'push');
            app.pushbutton211.ButtonPushedFcn = createCallbackFcn(app, @pushbutton211_Callback, true);
            app.pushbutton211.Tag = 'pushbutton211';
            app.pushbutton211.Text = 'Evidence accumulation';
            app.pushbutton212 = uibutton(app.uipanel26, 'push');
            app.pushbutton212.ButtonPushedFcn = createCallbackFcn(app, @pushbutton212_Callback, true);
            app.pushbutton212.Tag = 'pushbutton212';
            app.pushbutton212.Text = 'Mixed models';
            app.pushbutton214 = uibutton(app.uipanel26, 'push');
            app.pushbutton214.ButtonPushedFcn = createCallbackFcn(app, @pushbutton214_Callback, true);
            app.pushbutton214.Tag = 'pushbutton214';
            app.pushbutton214.Text = 'Maze learning';
            app.pushbutton225 = uibutton(app.uipanel26, 'push');
            app.pushbutton225.ButtonPushedFcn = createCallbackFcn(app, @pushbutton225_Callback, true);
            app.pushbutton225.Tag = 'pushbutton225';
            app.pushbutton225.Text = 'Decision to movement';
            app.pushbutton234 = uibutton(app.uipanel26, 'push');
            app.pushbutton234.ButtonPushedFcn = createCallbackFcn(app, @pushbutton234_Callback, true);
            app.pushbutton234.Tag = 'pushbutton234';
            app.pushbutton234.Text = 'Interoception';
            app.pushbutton244 = uibutton(app.uipanel26, 'push');
            app.pushbutton244.ButtonPushedFcn = createCallbackFcn(app, @pushbutton244_Callback, true);
            app.pushbutton244.Tag = 'pushbutton244';
            app.pushbutton244.Text = 'Movement planning';
            app.pushbutton263 = uibutton(app.uipanel26, 'push');
            app.pushbutton263.ButtonPushedFcn = createCallbackFcn(app, @pushbutton263_Callback, true);
            app.pushbutton263.Tag = 'pushbutton263';
            app.pushbutton263.Text = 'Active vision';
            app.pushbutton265 = uibutton(app.uipanel26, 'push');
            app.pushbutton265.ButtonPushedFcn = createCallbackFcn(app, @pushbutton265_Callback, true);
            app.pushbutton265.Tag = 'pushbutton265';
            app.pushbutton265.Text = 'Active sharing';
            app.pushbutton302 = uibutton(app.uipanel26, 'push');
            app.pushbutton302.ButtonPushedFcn = createCallbackFcn(app, @pushbutton302_Callback, true);
            app.pushbutton302.Tag = 'pushbutton302';
            app.pushbutton302.Text = 'Active reasoning';
            app.pushbutton307 = uibutton(app.uipanel26, 'push');
            app.pushbutton307.ButtonPushedFcn = createCallbackFcn(app, @pushbutton307_Callback, true);
            app.pushbutton307.Tag = 'pushbutton307';
            app.pushbutton307.Text = 'Pixels to places';
            app.uipanel27 = uipanel(app.figure1);
            app.uipanel27.Title = 'Communication and population modelling';
            app.uipanel27.Tag = 'uipanel27';
            app.pushbutton151 = uibutton(app.uipanel27, 'push');
            app.pushbutton151.ButtonPushedFcn = createCallbackFcn(app, @pushbutton151_Callback, true);
            app.pushbutton151.Tag = 'pushbutton151';
            app.pushbutton151.Text = 'Birdsong duet';
            app.pushbutton154 = uibutton(app.uipanel27, 'push');
            app.pushbutton154.ButtonPushedFcn = createCallbackFcn(app, @pushbutton154_Callback, true);
            app.pushbutton154.Tag = 'pushbutton154';
            app.pushbutton154.Text = 'Patterns';
            app.pushbutton228 = uibutton(app.uipanel27, 'push');
            app.pushbutton228.ButtonPushedFcn = createCallbackFcn(app, @pushbutton228_Callback, true);
            app.pushbutton228.Tag = 'pushbutton228';
            app.pushbutton228.Text = 'Niche construction';
            app.pushbutton262 = uibutton(app.uipanel27, 'push');
            app.pushbutton262.ButtonPushedFcn = createCallbackFcn(app, @pushbutton262_Callback, true);
            app.pushbutton262.Tag = 'pushbutton262';
            app.pushbutton262.Text = 'Climate';
            app.pushbutton299 = uibutton(app.uipanel27, 'push');
            app.pushbutton299.ButtonPushedFcn = createCallbackFcn(app, @pushbutton299_Callback, true);
            app.pushbutton299.Tag = 'pushbutton299';
            app.pushbutton299.Text = '+';
            app.pushbutton301 = uibutton(app.uipanel27, 'push');
            app.pushbutton301.ButtonPushedFcn = createCallbackFcn(app, @pushbutton301_Callback, true);
            app.pushbutton301.Tag = 'pushbutton301';
            app.pushbutton301.Text = '+';
            app.uipanel31 = uipanel(app.figure1);
            app.uipanel31.Title = 'Hierarchical (empirical) Bayes';
            app.uipanel31.Tag = 'uipanel31';
            app.pushbutton197 = uibutton(app.uipanel31, 'push');
            app.pushbutton197.ButtonPushedFcn = createCallbackFcn(app, @pushbutton197_Callback, true);
            app.pushbutton197.Tag = 'pushbutton197';
            app.pushbutton197.Text = 'General Linear Model';
            app.pushbutton198 = uibutton(app.uipanel31, 'push');
            app.pushbutton198.ButtonPushedFcn = createCallbackFcn(app, @pushbutton198_Callback, true);
            app.pushbutton198.Tag = 'pushbutton198';
            app.pushbutton198.Text = 'PEB group inversion';
            app.pushbutton199 = uibutton(app.uipanel31, 'push');
            app.pushbutton199.ButtonPushedFcn = createCallbackFcn(app, @pushbutton199_Callback, true);
            app.pushbutton199.Tag = 'pushbutton199';
            app.pushbutton199.Text = 'Simulated EEG analysis';
            app.pushbutton200 = uibutton(app.uipanel31, 'push');
            app.pushbutton200.ButtonPushedFcn = createCallbackFcn(app, @pushbutton200_Callback, true);
            app.pushbutton200.Tag = 'pushbutton200';
            app.pushbutton200.Text = 'PEB & grand averages';
            app.pushbutton201 = uibutton(app.uipanel31, 'push');
            app.pushbutton201.ButtonPushedFcn = createCallbackFcn(app, @pushbutton201_Callback, true);
            app.pushbutton201.Tag = 'pushbutton201';
            app.pushbutton201.Text = 'Bayes factors';
            app.pushbutton202 = uibutton(app.uipanel31, 'push');
            app.pushbutton202.ButtonPushedFcn = createCallbackFcn(app, @pushbutton202_Callback, true);
            app.pushbutton202.Tag = 'pushbutton202';
            app.pushbutton202.Text = 'Lindley paradox';
            app.pushbutton210 = uibutton(app.uipanel31, 'push');
            app.pushbutton210.ButtonPushedFcn = createCallbackFcn(app, @pushbutton210_Callback, true);
            app.pushbutton210.Tag = 'pushbutton210';
            app.pushbutton210.Text = 'PEB & session effects';
            app.pushbutton224 = uibutton(app.uipanel31, 'push');
            app.pushbutton224.ButtonPushedFcn = createCallbackFcn(app, @pushbutton224_Callback, true);
            app.pushbutton224.Tag = 'pushbutton224';
            app.pushbutton224.Text = 'Intelligent optimisation';
            app.pushbutton227 = uibutton(app.uipanel31, 'push');
            app.pushbutton227.ButtonPushedFcn = createCallbackFcn(app, @pushbutton227_Callback, true);
            app.pushbutton227.Tag = 'pushbutton227';
            app.pushbutton227.Text = 'CVA & RSA';
            app.pushbutton230 = uibutton(app.uipanel31, 'push');
            app.pushbutton230.ButtonPushedFcn = createCallbackFcn(app, @pushbutton230_Callback, true);
            app.pushbutton230.Tag = 'pushbutton230';
            app.pushbutton230.Text = 'Information gain';
            app.uipanel32 = uipanel(app.figure1);
            app.uipanel32.Title = 'Voice recognition and language';
            app.uipanel32.Tag = 'uipanel32';
            app.pushbutton239 = uibutton(app.uipanel32, 'push');
            app.pushbutton239.ButtonPushedFcn = createCallbackFcn(app, @pushbutton239_Callback, true);
            app.pushbutton239.Tag = 'pushbutton239';
            app.pushbutton239.Text = 'VR setup';
            app.pushbutton240 = uibutton(app.uipanel32, 'push');
            app.pushbutton240.ButtonPushedFcn = createCallbackFcn(app, @pushbutton240_Callback, true);
            app.pushbutton240.Tag = 'pushbutton240';
            app.pushbutton240.Text = 'Active listening';
            app.pushbutton226 = uibutton(app.uipanel32, 'push');
            app.pushbutton226.ButtonPushedFcn = createCallbackFcn(app, @pushbutton226_Callback, true);
            app.pushbutton226.Tag = 'pushbutton226';
            app.pushbutton226.Text = '20 questions';
            app.pushbutton241 = uibutton(app.uipanel32, 'push');
            app.pushbutton241.ButtonPushedFcn = createCallbackFcn(app, @pushbutton241_Callback, true);
            app.pushbutton241.Tag = 'pushbutton241';
            app.pushbutton241.Text = 'Voice recognition';
            app.pushbutton242 = uibutton(app.uipanel32, 'push');
            app.pushbutton242.ButtonPushedFcn = createCallbackFcn(app, @pushbutton242_Callback, true);
            app.pushbutton242.Tag = 'pushbutton242';
            app.pushbutton242.Text = 'Dictation';
            app.pushbutton243 = uibutton(app.uipanel32, 'push');
            app.pushbutton243.ButtonPushedFcn = createCallbackFcn(app, @pushbutton243_Callback, true);
            app.pushbutton243.Tag = 'pushbutton243';
            app.pushbutton243.Text = '+';
            app.uipanel34 = uipanel(app.figure1);
            app.uipanel34.Title = 'Sophisticated inference and structure learning';
            app.uipanel34.Tag = 'uipanel34';
            app.pushbutton258 = uibutton(app.uipanel34, 'push');
            app.pushbutton258.ButtonPushedFcn = createCallbackFcn(app, @pushbutton258_Callback, true);
            app.pushbutton258.Tag = 'pushbutton258';
            app.pushbutton258.Text = 'Sophisticated AI';
            app.pushbutton259 = uibutton(app.uipanel34, 'push');
            app.pushbutton259.ButtonPushedFcn = createCallbackFcn(app, @pushbutton259_Callback, true);
            app.pushbutton259.Tag = 'pushbutton259';
            app.pushbutton259.Text = 'Mazes';
            app.pushbutton267 = uibutton(app.uipanel34, 'push');
            app.pushbutton267.ButtonPushedFcn = createCallbackFcn(app, @pushbutton267_Callback, true);
            app.pushbutton267.Tag = 'pushbutton267';
            app.pushbutton267.Text = 'dSprites';
            app.pushbutton268 = uibutton(app.uipanel34, 'push');
            app.pushbutton268.ButtonPushedFcn = createCallbackFcn(app, @pushbutton268_Callback, true);
            app.pushbutton268.Tag = 'pushbutton268';
            app.pushbutton268.Text = 'BlockWorld';
            app.pushbutton269 = uibutton(app.uipanel34, 'push');
            app.pushbutton269.ButtonPushedFcn = createCallbackFcn(app, @pushbutton269_Callback, true);
            app.pushbutton269.Tag = 'pushbutton269';
            app.pushbutton269.Text = 'MNIST';
            app.pushbutton285 = uibutton(app.uipanel34, 'push');
            app.pushbutton285.ButtonPushedFcn = createCallbackFcn(app, @pushbutton285_Callback, true);
            app.pushbutton285.Tag = 'pushbutton285';
            app.pushbutton285.Text = '+';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Dynamic expectation maximisation (DEM) is a variational treatment of hierarchical, '; 'nonlinear dynamic or static models.  It uses a fixed-form Laplace assumption to '; 'approximate the conditional, variational or ensemble density of unknown states and '; 'parameters.  This is an approximation to the density that would obtain from Variational '; 'Filtering (VF) in generalized coordinates of motion. Start with the demonstration of'; 'VF using a simple convolution model and compare the results with a DEM.  We also '; 'demonstrate the inversion of increasingly complicated models; ranging from a simple '; 'General Linear Model to a Lorenz attractor.  DEM is a special case of filtering in '; 'generalised coordinates of motion, which can include parameters (see generalised '; 'filtering). Both sorts of schemes can be used to analyse data or (as shown) used to '; 'simulate various aspects of perpetual inference, action and ensemble dynamics. It is '; 'anticipated that people will examine the routines called to fully understand their nature.'};
            app.uipanel28 = uipanel(app.figure1);
            app.uipanel28.Title = 'Behavioural and clinical modelling';
            app.uipanel28.Tag = 'uipanel28';
            app.pushbutton252 = uibutton(app.uipanel28, 'push');
            app.pushbutton252.ButtonPushedFcn = createCallbackFcn(app, @pushbutton252_Callback, true);
            app.pushbutton252.Tag = 'pushbutton252';
            app.pushbutton252.Text = 'COVID-19';
            app.pushbutton261 = uibutton(app.uipanel28, 'push');
            app.pushbutton261.ButtonPushedFcn = createCallbackFcn(app, @pushbutton261_Callback, true);
            app.pushbutton261.Tag = 'pushbutton261';
            app.pushbutton261.Text = '+';
            app.pushbutton250 = uibutton(app.uipanel28, 'push');
            app.pushbutton250.ButtonPushedFcn = createCallbackFcn(app, @pushbutton250_Callback, true);
            app.pushbutton250.Tag = 'pushbutton250';
            app.pushbutton250.Text = ' Nosology';
            app.pushbutton248 = uibutton(app.uipanel28, 'push');
            app.pushbutton248.ButtonPushedFcn = createCallbackFcn(app, @pushbutton248_Callback, true);
            app.pushbutton248.Tag = 'pushbutton248';
            app.pushbutton248.Text = 'Meta-modelling';
            app.pushbutton249 = uibutton(app.uipanel28, 'push');
            app.pushbutton249.ButtonPushedFcn = createCallbackFcn(app, @pushbutton249_Callback, true);
            app.pushbutton249.Tag = 'pushbutton249';
            app.pushbutton249.Text = 'Choices';
            app.pushbutton251 = uibutton(app.uipanel28, 'push');
            app.pushbutton251.ButtonPushedFcn = createCallbackFcn(app, @pushbutton251_Callback, true);
            app.pushbutton251.Tag = 'pushbutton251';
            app.pushbutton251.Text = '+';
            app.pushbutton300 = uibutton(app.uipanel28, 'push');
            app.pushbutton300.ButtonPushedFcn = createCallbackFcn(app, @pushbutton300_Callback, true);
            app.pushbutton300.Tag = 'pushbutton300';
            app.pushbutton300.Text = 'Social norms';
            app.pushbutton266 = uibutton(app.figure1, 'push');
            app.pushbutton266.ButtonPushedFcn = createCallbackFcn(app, @pushbutton266_Callback, true);
            app.pushbutton266.Tag = 'pushbutton266';
            app.pushbutton266.Text = 'Psychophysics';
            app.uipanel37 = uipanel(app.figure1);
            app.uipanel37.Title = 'Inductive inference';
            app.uipanel37.Tag = 'uipanel37';
            app.pushbutton282 = uibutton(app.uipanel37, 'push');
            app.pushbutton282.ButtonPushedFcn = createCallbackFcn(app, @pushbutton282_Callback, true);
            app.pushbutton282.Tag = 'pushbutton282';
            app.pushbutton282.Text = 'Maze';
            app.pushbutton283 = uibutton(app.uipanel37, 'push');
            app.pushbutton283.ButtonPushedFcn = createCallbackFcn(app, @pushbutton283_Callback, true);
            app.pushbutton283.Tag = 'pushbutton283';
            app.pushbutton283.Text = 'Tower';
            app.pushbutton284 = uibutton(app.uipanel37, 'push');
            app.pushbutton284.ButtonPushedFcn = createCallbackFcn(app, @pushbutton284_Callback, true);
            app.pushbutton284.Tag = 'pushbutton284';
            app.pushbutton284.Text = 'Pong';
            app.uipanel38 = uipanel(app.figure1);
            app.uipanel38.Title = 'Planning from pixels (renormalising generative models)';
            app.uipanel38.Tag = 'uipanel38';
            app.pushbutton286 = uibutton(app.uipanel38, 'push');
            app.pushbutton286.ButtonPushedFcn = createCallbackFcn(app, @pushbutton286_Callback, true);
            app.pushbutton286.Tag = 'pushbutton286';
            app.pushbutton286.Enable = 'off';
            app.pushbutton286.Text = 'Drone';
            app.pushbutton287 = uibutton(app.uipanel38, 'push');
            app.pushbutton287.ButtonPushedFcn = createCallbackFcn(app, @pushbutton287_Callback, true);
            app.pushbutton287.Tag = 'pushbutton287';
            app.pushbutton287.Text = '+';
            app.pushbutton288 = uibutton(app.uipanel38, 'push');
            app.pushbutton288.ButtonPushedFcn = createCallbackFcn(app, @pushbutton288_Callback, true);
            app.pushbutton288.Tag = 'pushbutton288';
            app.pushbutton288.Text = 'Games';
            app.pushbutton289 = uibutton(app.uipanel38, 'push');
            app.pushbutton289.ButtonPushedFcn = createCallbackFcn(app, @pushbutton289_Callback, true);
            app.pushbutton289.Tag = 'pushbutton289';
            app.pushbutton289.Text = 'Vision';
            app.pushbutton290 = uibutton(app.uipanel38, 'push');
            app.pushbutton290.ButtonPushedFcn = createCallbackFcn(app, @pushbutton290_Callback, true);
            app.pushbutton290.Tag = 'pushbutton290';
            app.pushbutton290.Text = 'Sound';
            app.pushbutton291 = uibutton(app.uipanel38, 'push');
            app.pushbutton291.ButtonPushedFcn = createCallbackFcn(app, @pushbutton291_Callback, true);
            app.pushbutton291.Tag = 'pushbutton291';
            app.pushbutton291.Text = 'Image';
            app.pushbutton292 = uibutton(app.uipanel38, 'push');
            app.pushbutton292.ButtonPushedFcn = createCallbackFcn(app, @pushbutton292_Callback, true);
            app.pushbutton292.Tag = 'pushbutton292';
            app.pushbutton292.Enable = 'off';
            app.pushbutton292.Text = '+';
            app.pushbutton293 = uibutton(app.uipanel38, 'push');
            app.pushbutton293.ButtonPushedFcn = createCallbackFcn(app, @pushbutton293_Callback, true);
            app.pushbutton293.Tag = 'pushbutton293';
            app.pushbutton293.Text = 'Planning';
            app.pushbutton294 = uibutton(app.uipanel38, 'push');
            app.pushbutton294.ButtonPushedFcn = createCallbackFcn(app, @pushbutton294_Callback, true);
            app.pushbutton294.Tag = 'pushbutton294';
            app.pushbutton294.Text = '+';
            app.pushbutton295 = uibutton(app.uipanel38, 'push');
            app.pushbutton295.ButtonPushedFcn = createCallbackFcn(app, @pushbutton295_Callback, true);
            app.pushbutton295.Tag = 'pushbutton295';
            app.pushbutton295.Text = '+';
            app.pushbutton296 = uibutton(app.uipanel38, 'push');
            app.pushbutton296.ButtonPushedFcn = createCallbackFcn(app, @pushbutton296_Callback, true);
            app.pushbutton296.Tag = 'pushbutton296';
            app.pushbutton296.Enable = 'off';
            app.pushbutton296.Text = '++';
            app.pushbutton298 = uibutton(app.uipanel38, 'push');
            app.pushbutton298.ButtonPushedFcn = createCallbackFcn(app, @pushbutton298_Callback, true);
            app.pushbutton298.Tag = 'pushbutton298';
            app.pushbutton298.Text = '+';
            app.uipanel39 = uipanel(app.figure1);
            app.uipanel39.Title = 'Continuous planning';
            app.uipanel39.Tag = 'uipanel39';
            app.pushbutton309 = uibutton(app.uipanel39, 'push');
            app.pushbutton309.ButtonPushedFcn = createCallbackFcn(app, @pushbutton309_Callback, true);
            app.pushbutton309.Tag = 'pushbutton309';
            app.pushbutton309.Text = 'Intercept';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DEM_demo_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DEM_demo_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end